classdef powsys < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        LineofResistanceEditField       matlab.ui.control.NumericEditField
        LineofResistanceEditFieldLabel  matlab.ui.control.Label
        LineofCapsitorEditField         matlab.ui.control.NumericEditField
        LineofCapsitorEditFieldLabel    matlab.ui.control.Label
        LineofInductorEditField         matlab.ui.control.NumericEditField
        LineofInductorEditFieldLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        DistanceofbundlesEditField      matlab.ui.control.NumericEditField
        DistanceofbundlesEditFieldLabel  matlab.ui.control.Label
        BundlsDropDown                  matlab.ui.control.DropDown
        BundlsDropDownLabel             matlab.ui.control.Label
        c1c2EditField                   matlab.ui.control.NumericEditField
        c1c2EditFieldLabel              matlab.ui.control.Label
        b1c2EditField                   matlab.ui.control.NumericEditField
        b1c2EditFieldLabel              matlab.ui.control.Label
        a2c2EditField                   matlab.ui.control.NumericEditField
        a2c2EditFieldLabel              matlab.ui.control.Label
        a1b1EditField                   matlab.ui.control.NumericEditField
        a1b1EditFieldLabel              matlab.ui.control.Label
        a2b1EditField                   matlab.ui.control.NumericEditField
        a2b1EditFieldLabel              matlab.ui.control.Label
        b2c2EditField                   matlab.ui.control.NumericEditField
        b2c2EditFieldLabel              matlab.ui.control.Label
        b1c1EditField                   matlab.ui.control.NumericEditField
        b1c1EditFieldLabel              matlab.ui.control.Label
        a2c1EditField                   matlab.ui.control.NumericEditField
        a2c1EditFieldLabel              matlab.ui.control.Label
        a1c2EditField                   matlab.ui.control.NumericEditField
        a1c2EditFieldLabel              matlab.ui.control.Label
        a1b2EditField                   matlab.ui.control.NumericEditField
        a1b2EditFieldLabel              matlab.ui.control.Label
        b2c1EditField                   matlab.ui.control.NumericEditField
        b2c1EditFieldLabel              matlab.ui.control.Label
        b1b2EditField                   matlab.ui.control.NumericEditField
        b1b2EditFieldLabel              matlab.ui.control.Label
        a2b2EditField                   matlab.ui.control.NumericEditField
        a2b2EditFieldLabel              matlab.ui.control.Label
        a1c1EditField                   matlab.ui.control.NumericEditField
        a1c1EditFieldLabel              matlab.ui.control.Label
        a1a2EditField                   matlab.ui.control.NumericEditField
        a1a2EditFieldLabel              matlab.ui.control.Label
        TransmissionLineLengthEditField  matlab.ui.control.NumericEditField
        TransmissionLineLengthEditFieldLabel  matlab.ui.control.Label
        coreDropDown                    matlab.ui.control.DropDown
        coreDropDownLabel               matlab.ui.control.Label
        TEMButtonGroup                  matlab.ui.container.ButtonGroup
        Button_4                        matlab.ui.control.RadioButton
        Button_3                        matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        calculateButton                 matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)


            GMD=(app.c1c2EditField.Value * app.a2c2EditField.Value* app.a1a2EditField.Value* app.a1b1EditField.Value* ...
                app.a1b2EditField.Value* app.a1c1EditField.Value* app.a1c2EditField.Value* app.b1c1EditField.Value* ...
                app.b1c2EditField.Value* app.a2b1EditField.Value* app.a2b2EditField.Value* app.b2c1EditField.Value* ...
                app.b2c2EditField.Value* app.a2c1EditField.Value)^(1/12);
            if app.BundlsDropDown.Value == '3'

                switch (app.coreDropDown.Value)
                    case 'Joree'
                        d = 50.80/2*10^-3;
                        r = 20.33*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;
                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/3);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/3);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*24.5*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*26.2*10^-3;
                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*28.1*10^-3;
                        end


                    case 'Kiwi'
                        d = 45.77/2*10^-3;
                        r = 18.14*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/3);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/3);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*27.7*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*30*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*32*10^-3;
                        end
                    case 'Falcon'
                        d = 39.24/2*10^-3;
                        r = 16.28*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/3);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/3);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*37.4*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*40.8*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*44.3*10^-3;
                        end

                    case 'Dipper'
                        d = 35.2/2*10^-3;
                        r = 13.99*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/3);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/3);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*44.9*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*49*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*53.6*10^-3;
                        end

                    case 'Finch'
                        d = 32.84/2*10^-3;
                        r = 13.29*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/3);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/3);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*52.3*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*57.3*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*62.3*10^-3;
                        end
                end
            elseif app.BundlsDropDown.Value == '2'

                switch (app.coreDropDown.Value)

                    case 'Joree'
                        d = 50.80/2*10^-3;
                        r = 20.33*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;


                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*24.5*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*26.2*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*28.1*10^-3;
                        end
                    case 'Kiwi'
                        d = 45.77/2*10^-3;
                        r = 18.14*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;
                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*27.7*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*30*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*32*10^-3;
                        end
                    case 'Falcon'
                        d = 39.24/2*10^-3;
                        r = 16.28*10^-3;

                        cm = app.DistanceofbundlesEditField.Value^2 ;
                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*37.4*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*40.8*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*44.3*10^-3;
                        end
                    case 'Dipper'
                        d = 35.2/2*10^-3;
                        r = 13.99*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*44.9*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*49*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*53.6*10^-3;
                        end

                    case 'Finch'
                        d = 32.84/2*10^-3;
                        r = 13.29*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = sqrt(r*cm);
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = sqrt(d*cm);
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*52.3*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*57.3*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*62.3*10^-3;
                        end
                end
            else

                switch (app.coreDropDown.Value)

                    case 'Joree'
                        d = 50.80/2*10^-3;
                        r = 20.33*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL =r;
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = d;
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*24.5*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*26.2*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*28.1*10^-3;
                        end
                    case 'Kiwi'
                        d = 45.77/2*10^-3;
                        r = 18.14*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = r;
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = d;
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*27.7*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*30*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*32*10^-3;
                        end
                    case 'Falcon'
                        d = 39.24/2*10^-3;
                        r = 16.28*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = r;
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = d;
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*37.4*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*40.8*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*44.3*10^-3;
                        end
                    case 'Dipper'
                        d = 35.2/2*10^-3;
                        r = 13.99*10^-3;
                        cm = app.DistanceofbundlesEditField.Value^2 ;

                        gmrL = r;
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = d;
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*44.9*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*49*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*53.6*10^-3;
                        end

                    case 'Finch'
                        d = 32.84/2*10^-3;
                        r = 13.29*10^-3;

                        cm = app.DistanceofbundlesEditField.Value^2 ;
                        gmrL = r;
                        gmrL_a = sqrt(gmrL*app.a1a2EditField.Value);
                        gmrL_b = sqrt(gmrL*app.b1b2EditField.Value);
                        gmrL_c = sqrt(gmrL*app.c1c2EditField.Value);
                        GMR_L = (gmrL_c*gmrL_b*gmrL_a)^(1/2);

                        gmrC = d;
                        gmrC_a = sqrt(gmrC*app.a1a2EditField.Value);
                        gmrC_b = sqrt(gmrC*app.b1b2EditField.Value);
                        gmrC_c = sqrt(gmrC*app.c1c2EditField.Value);
                        GMR_C = (gmrC_c*gmrC_b*gmrC_a)^(1/2);

                        L = 2*10^-7*log(GMD/GMR_L);
                        C = 2*pi*8.854*10^-12/log(GMD/GMR_C);

                        app.LineofInductorEditField.Value = app.TransmissionLineLengthEditField.Value*L;

                        app.LineofCapsitorEditField.Value = app.TransmissionLineLengthEditField.Value*C;

                        app.LineofInductorEditField.Value

                        if app.Button_2.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*52.3*10^-3;
                        elseif app.Button_3.Value == true
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*57.3*10^-3;

                        else
                            app.LineofResistanceEditField.Value = app.TransmissionLineLengthEditField.Value*62.3*10^-3;
                        end
                end
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {377, 377};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2024-02-03 220709.png');
            app.LineofInductorEditFieldLabel = uilabel(app.LeftPanel);
            app.LineofInductorEditFieldLabel.Text = 'Line of Inductor';
            app.LineofInductorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LineofCapsitorEditFieldLabel = uilabel(app.LeftPanel);
            app.LineofCapsitorEditFieldLabel.Text = 'Line of Capsitor';
            app.LineofCapsitorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LineofResistanceEditFieldLabel = uilabel(app.LeftPanel);
            app.LineofResistanceEditFieldLabel.Text = 'Line of Resistance';
            app.LineofResistanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.calculateButton = uibutton(app.RightPanel, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.TEMButtonGroup = uibuttongroup(app.RightPanel);
            app.TEMButtonGroup.Title = 'TEM';
            app.Button_2 = uiradiobutton(app.TEMButtonGroup);
            app.Button_2.Text = '25';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.TEMButtonGroup);
            app.Button_3.Text = '50';
            app.Button_4 = uiradiobutton(app.TEMButtonGroup);
            app.Button_4.Text = '75';
            app.coreDropDownLabel = uilabel(app.RightPanel);
            app.coreDropDownLabel.Text = 'core';
            app.coreDropDown = uidropdown(app.RightPanel);
            app.coreDropDown.Items = {'Joree', 'Kiwi', 'Falcon', 'Dipper', 'Finch'};
            app.coreDropDown.Value = 'Joree';
            app.TransmissionLineLengthEditFieldLabel = uilabel(app.RightPanel);
            app.TransmissionLineLengthEditFieldLabel.Text = 'Transmission Line Length';
            app.TransmissionLineLengthEditField = uieditfield(app.RightPanel, 'numeric');
            app.a1a2EditFieldLabel = uilabel(app.RightPanel);
            app.a1a2EditFieldLabel.Text = 'a1a2';
            app.a1a2EditField = uieditfield(app.RightPanel, 'numeric');
            app.a1c1EditFieldLabel = uilabel(app.RightPanel);
            app.a1c1EditFieldLabel.Text = 'a1c1';
            app.a1c1EditField = uieditfield(app.RightPanel, 'numeric');
            app.a2b2EditFieldLabel = uilabel(app.RightPanel);
            app.a2b2EditFieldLabel.Text = 'a2b2';
            app.a2b2EditField = uieditfield(app.RightPanel, 'numeric');
            app.b1b2EditFieldLabel = uilabel(app.RightPanel);
            app.b1b2EditFieldLabel.Text = 'b1b2';
            app.b1b2EditField = uieditfield(app.RightPanel, 'numeric');
            app.b2c1EditFieldLabel = uilabel(app.RightPanel);
            app.b2c1EditFieldLabel.Text = 'b2c1';
            app.b2c1EditField = uieditfield(app.RightPanel, 'numeric');
            app.a1b2EditFieldLabel = uilabel(app.RightPanel);
            app.a1b2EditFieldLabel.Text = 'a1b2';
            app.a1b2EditField = uieditfield(app.RightPanel, 'numeric');
            app.a1c2EditFieldLabel = uilabel(app.RightPanel);
            app.a1c2EditFieldLabel.Text = 'a1c2';
            app.a1c2EditField = uieditfield(app.RightPanel, 'numeric');
            app.a2c1EditFieldLabel = uilabel(app.RightPanel);
            app.a2c1EditFieldLabel.Text = 'a2c1';
            app.a2c1EditField = uieditfield(app.RightPanel, 'numeric');
            app.b1c1EditFieldLabel = uilabel(app.RightPanel);
            app.b1c1EditFieldLabel.Text = 'b1c1';
            app.b1c1EditField = uieditfield(app.RightPanel, 'numeric');
            app.b2c2EditFieldLabel = uilabel(app.RightPanel);
            app.b2c2EditFieldLabel.Text = 'b2c2';
            app.b2c2EditField = uieditfield(app.RightPanel, 'numeric');
            app.a2b1EditFieldLabel = uilabel(app.RightPanel);
            app.a2b1EditFieldLabel.Text = 'a2b1';
            app.a2b1EditField = uieditfield(app.RightPanel, 'numeric');
            app.a1b1EditFieldLabel = uilabel(app.RightPanel);
            app.a1b1EditFieldLabel.Text = 'a1b1';
            app.a1b1EditField = uieditfield(app.RightPanel, 'numeric');
            app.a2c2EditFieldLabel = uilabel(app.RightPanel);
            app.a2c2EditFieldLabel.Text = 'a2c2';
            app.a2c2EditField = uieditfield(app.RightPanel, 'numeric');
            app.b1c2EditFieldLabel = uilabel(app.RightPanel);
            app.b1c2EditFieldLabel.Text = 'b1c2';
            app.b1c2EditField = uieditfield(app.RightPanel, 'numeric');
            app.c1c2EditFieldLabel = uilabel(app.RightPanel);
            app.c1c2EditFieldLabel.Text = 'c1c2';
            app.c1c2EditField = uieditfield(app.RightPanel, 'numeric');
            app.BundlsDropDownLabel = uilabel(app.RightPanel);
            app.BundlsDropDownLabel.Text = 'Bundls';
            app.BundlsDropDown = uidropdown(app.RightPanel);
            app.BundlsDropDown.Items = {'1', '2', '3'};
            app.BundlsDropDown.Value = '2';
            app.DistanceofbundlesEditFieldLabel = uilabel(app.RightPanel);
            app.DistanceofbundlesEditFieldLabel.Text = 'Distance of bundles';
            app.DistanceofbundlesEditField = uieditfield(app.RightPanel, 'numeric');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = powsys

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end